classdef Clueless < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        clFigure         matlab.ui.Figure
        GclBoard         matlab.ui.container.GridLayout
        clBoard          matlab.ui.container.Panel
        GclLayout        matlab.ui.container.GridLayout
        MovesListPanel   matlab.ui.container.Panel
        Gmoves           matlab.ui.container.GridLayout
        Moves            matlab.ui.control.Table
        Check            matlab.ui.control.Button
        NextMove         matlab.ui.control.Button
        CardsDistPanel   matlab.ui.container.Panel
        Gcards           matlab.ui.container.GridLayout
        EnvelopeC3       matlab.ui.control.Spinner
        EnvelopeC2       matlab.ui.control.Spinner
        EnvelopeC1       matlab.ui.control.Spinner
        EnvelopeCsLabel  matlab.ui.control.Label
        CommonC3         matlab.ui.control.EditField
        CommonC2         matlab.ui.control.EditField
        CommonC1         matlab.ui.control.EditField
        CommonCsLabel    matlab.ui.control.Label
        PlayersC3        matlab.ui.control.EditField
        PlayersC2        matlab.ui.control.EditField
        PlayersC1        matlab.ui.control.EditField
        PlayersCsLabel   matlab.ui.control.Label
        GameSetupPanel   matlab.ui.container.Panel
        Gsetup           matlab.ui.container.GridLayout
        LoadGame         matlab.ui.control.DropDown
        ModeSwitch       matlab.ui.control.Switch
        LetsPlay         matlab.ui.control.Button
        NumYou           matlab.ui.control.Spinner
        NumYouLabel      matlab.ui.control.Label
        NumPlayers       matlab.ui.control.Spinner
        NumPlayersLabel  matlab.ui.control.Label
        NumCards         matlab.ui.control.Spinner
        NumCardsLabel    matlab.ui.control.Label
        NumCats          matlab.ui.control.Spinner
        NumCatsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Program Version
        Mver=0
        mver=1
        prog=22 %χ
        rels=1
        %% .NET stuff
        speaker=[]
        %% Files & Folders
        mfilepath=fileparts(mfilename('fullpath'))
        currpath=pwd
        %clmat=[]
        %dldf=[]
        %% Useful Variables
        yourCards={[],[],[]}
        commonCards={[],[],[]}
        moves=[]
        movesTable=table()
        solV=[]
        %% Useful Colours & Styles
        catColor=[0.18,0.75,0.94;0.23,0.67,0.20;0.82,0.02,0.55] %Add more colours for more categories!
        catStyle=@(col)uistyle("BackgroundColor",col,FontColor='w')
        pyColor=[0.75 0.36 0.98]
        pyStyle=uistyle("BackgroundColor",[0.75 0.36 0.98])
    end

    properties (Dependent, Access = private)
        num3
        numC
        numP
        numY
        solE
    end

    methods %for Dependent properties

        function t = get.num3(app)
            t=app.NumCats.Value;
        end

        function n = get.numC(app)
            n=app.NumCards.Value;
        end

        function m = get.numP(app)
            m=app.NumPlayers.Value;
        end
        
        function y = get.numY(app)
            y=app.NumYou.Value;
        end

        function s = get.solE(app)
            s=[app.EnvelopeC1.Value app.EnvelopeC2.Value app.EnvelopeC3.Value];
        end

    end
    
    methods (Access = private)
        
        function tryck(app, event, funcall)
            try
                funcall(app, event);
            catch err
                if ~isempty(app.speaker)
                    SpeakAsync(app.speaker,'Well, that was unexpected!');
                end
                detail=struct2cell(err.stack)';
                detail=strcat(detail(:,2),' in Line-',cellstr(cellfun(@num2str,detail(:,3),'UniformOutput',0)));
                uialert(app.clFigure,[[err.identifier,': '];err.message;flipud(detail)],'Something Went Wrong!😱','Icon',"error");
            end
        end

        function knowMat = inferCards(app)
            % The amazing work of Stefan Abendroth, WANG Zi-Xiang, JKMSMKJ, Vasilis Bellos
            movesT=app.moves(1:height(app.Moves.Data),:);
            knowMat=cumsum(ones(app.numP,app.numC,app.num3))~=app.numY;
            for deck=str2num('repmat(1:3,1,3)')
                [ind move5 knowMat ...
                    knowMat(ind(1:3==move5 | ~move5 & sum(knowMat(ind),2)==1 & knowMat(ind))) ...
                    knowMat(app.numY,[app.yourCards{deck},app.commonCards{deck}],deck) ...
                    knowMat(ind(move5<0,:))]=...
                    deal(movesT(:,1)+app.numP*(app.numC*str2num('0 1 2')+movesT(:,2:4)-1),...
                    movesT(:,5),...
                    2.^(sum(knowMat>0,2:3)==numel(cell2mat(app.yourCards))).*(knowMat==max(knowMat) & (sum(2-max(knowMat))==1)<knowMat),...
                    2,2,0);
            end
        end

        function gameLoaded(app)
            set(app.PlayersC1(:),Value="{?}");
            set(app.PlayersC2(:),Value="{?}");
            set(app.PlayersC3(:),Value="{?}");
            app.PlayersC1(app.numY).Value="["+strjoin(string(app.yourCards{1}),",")+"]";
            app.PlayersC2(app.numY).Value="["+strjoin(string(app.yourCards{2}),",")+"]";
            app.PlayersC3(app.numY).Value="["+strjoin(string(app.yourCards{3}),",")+"]";
            app.CommonC1.Value="["+strjoin(string(app.commonCards{1}),",")+"]";
            app.CommonC2.Value="["+strjoin(string(app.commonCards{2}),",")+"]";
            app.CommonC3.Value="["+strjoin(string(app.commonCards{3}),",")+"]";
            app.EnvelopeC1.Value=0;
            app.EnvelopeC2.Value=0;
            app.EnvelopeC3.Value=0;
            if ~isempty(app.moves)
                player="👤"+string(mod(0:height(app.moves)-1,app.numP)'+1);
                asked="👤"+string(app.moves(:,1));
                reply=dictionary(-1,"<font color=#FF000>NO</font>",0,"<font color=#2EBFF0>Y</font><font color=#3BAB33>E</font>"+...
                    "<font color=#D1058C>S</font>",1,"Cat.1",2,"Cat.2",3,"Cat.3");
                app.movesTable=table(player,asked,app.moves(:,2),app.moves(:,3),app.moves(:,4),reply(app.moves(:,5)));
            else
                app.movesTable=table();
            end
            app.Moves.Data=[];
        end

        function applyCellStyles(app,endRow)
            col=find(contains(app.Moves.Data(end,1:2),string(app.numY)));
            if ~isempty(col)
                addStyle(app.Moves,app.pyStyle,"cell",[endRow,col]);
            end
            col=double(extract(app.Moves.Data(end,6),"."+digitsPattern))*10;
            if ~isempty(col)
                addStyle(app.Moves,app.catStyle(app.catColor(col,:)),"cell",[endRow,6]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Setting up the GUI
            h=uiprogressdlg(app.clFigure,'Title','Setting up GUI...','Message','Please wait a moment...','Indeterminate','on');drawnow
            movegui(app.clFigure,'center');
            NumPlayersValueChanged(app,[]);
            addStyle(app.Moves,uistyle("Interpreter","html"));
            addStyle(app.Moves,uistyle("HorizontalAlignment","center"),"column",1:6);
            addStyle(app.Moves,app.catStyle(app.catColor(1,:)),"column",3);
            addStyle(app.Moves,app.catStyle(app.catColor(2,:)),"column",4);
            addStyle(app.Moves,app.catStyle(app.catColor(3,:)),"column",5);
            %% Loading/Saving startup folder & settings...
            % app.clmat=fullfile(getenv('appdata'),'Clueless','application','cl.mat');
            % if exist(app.clmat,'file')==2
            %     load(app.clmat,'SuF','Opts');
            %     app.currpath=SuF;
            %     if ~isMATLABReleaseOlderThan("R2025a")
            %         app.AppTheme.Enable="on";
            %         if isfield(Opts,'ApThm') && app.AppTheme.State~=Opts.ApThm
            %             app.AppTheme.State=Opts.ApThm;
            %             AppTheme_Callback(app,[]);
            %         else
            %             Opts.ApThm=app.AppTheme.State;
            %             save(app.clmat,'Opts','-append');
            %         end
            %     end
            % else
            %     SuF=app.currpath;
            %     Opts.ApThm=app.AppTheme.State;
            %     try
            %         if exist(fullfile(getenv('appdata'),'Clueless','application'),'dir')~=7
            %             mkdir(fullfile(getenv('appdata'),'Clueless'),'application');
            %         end
            %         save(app.clmat,'SuF','Opts');
            %     catch error
            %         uialert(app.clFigure,error.message,'No Write-Access!😯','Icon','warning');
            %     end
            % end
            %% Assigning callbacks via try-catch
            % 
            %% Finishing up...
            h.Message='GUI set up.';h.Icon='Success';drawnow
            try
                NET.addAssembly('System.Speech');
                app.speaker=System.Speech.Synthesis.SpeechSynthesizer;
                SpeakAsync(app.speaker,'Let''s Play Clueless!');
            catch me
                getReport(me,'extended');
            end
            close(h);
            focus(app.LoadGame);
        end

        % Theme changed function: clFigure
        function clFigureThemeChanged(app, event)
            if app.AppTheme.State==0
                app.clFigure.Theme.BaseColorStyle="dark";
            else
                app.clFigure.Theme.BaseColorStyle="light";
            end
            %app.switchSview.BackgroundColor=fliplightness(app.switchSview.BackgroundColor);
        end

        % Value changed function: LoadGame
        function LoadGameValueChanged(app, event)
            switch app.LoadGame.Value
                case 0 %4 players, 4 cards 
                    app.NumPlayers.Value=4;
                    app.NumCards.Value=4;
                    app.NumYou.Value=4;
                    app.yourCards={[1 3],[],[]};
                    app.commonCards={[],[3],[]};
                    app.moves=[3 1 4 3 0;1 3 4 4 0;2 3 1 3 0;1 4 2 2 -1;4 3 1 1 0;3 2 2 2 0;2 1 1 4 0;2 4 2 3 3;3 3 2 1 -1;1 4 4 2 -1;...
                        2 4 2 1 -1;1 2 2 4 1];
                    app.solV=[4 2 1];
                case 1 %4 players, 7 cards
                    app.NumPlayers.Value=4;
                    app.NumCards.Value=7;
                    app.NumYou.Value=4;
                    app.yourCards={4,[1 3],5};
                    app.commonCards={1,[],7};
                    app.moves=[4 6 1 1 0;3 4 7 1 0;2 6 7 5 0;2 7 7 1 1;4 6 3 6 0;3 4 7 4 -1;2 4 3 5 -1;2 2 6 2 -1;2 4 4 1 0;3 3 3 4 -1;...
                        4 7 2 5 0;3 3 5 6 3;3 3 1 6 0;3 3 2 6 0;2 3 5 5 -1;1 2 2 1 2;4 3 1 6 0;4 4 2 1 0;1 6 7 5 0;3 7 5 3 -1;2 3 4 5 0;...
                        1 3 3 5 -1;1 6 5 4 0;1 2 2 6 2;4 3 6 2 -1;4 2 2 1 -1;2 6 4 4 0;2 3 6 3 -1;2 4 3 5 -1;4 3 5 6 -1;4 5 5 3 -1;1 3 4 4 3;...
                        3 2 3 5 0;1 3 2 6 0;2 6 4 3 0;2 6 5 1 1;4 7 4 3 -1;1 2 1 4 0;1 3 4 5 -1;3 7 7 4 -1;3 6 3 6 0;3 3 7 1 0;1 3 1 5 -1;...
                        3 5 2 1 3;3 6 4 6 0;3 2 7 6 0;1 4 4 4 0;1 7 5 3 2];
                    app.solV=[3 6 3];
                case 2 %6 players, 6 cards
                    app.NumPlayers.Value=6;
                    app.NumCards.Value=6;
                    app.NumYou.Value=3;
                    app.yourCards={4,6,[]};
                    app.commonCards={1,5,3};
                    app.moves=[5 5 2 1 -1;6 2 3 5 0;1 6 2 2 3;2 4 2 5 -1;6 4 2 6 -1;4 4 4 4 0;6 3 1 6 0;5 4 2 5 -1;4 2 4 4 2;3 6 6 4 0;...
                        2 4 2 2 -1;4 2 4 6 0;5 6 3 6 0;6 2 1 4 -1;6 2 1 5 -1;6 4 2 6 -1;6 5 6 5 -1;4 5 6 1 0;3 2 3 1 -1;6 4 6 5 -1;4 6 1 2 -1;...
                        2 4 3 2 -1;1 2 6 6 -1;4 5 2 4 0;2 2 2 4 -1;3 2 1 5 -1;6 5 3 5 2;3 2 1 4 -1;4 5 6 5 0;4 6 1 5 -1;4 2 4 6 0;5 5 2 4 -1;...
                        6 2 1 5 -1;6 3 2 4 0;4 5 4 4 0;3 4 4 6 0;5 3 4 6 -1;5 4 1 4 0;1 6 1 6 -1;6 6 2 1 -1;2 3 4 6 0;4 4 2 1 -1;2 6 3 1 0;...
                        3 3 6 5 0;2 5 3 4 -1;1 3 2 6 -1;2 5 4 4 -1;3 5 6 4 0;5 2 3 4 -1;3 4 6 2 0;5 6 3 1 1;6 6 2 5 -1;3 4 6 4 0;3 4 6 4 0;...
                        5 2 6 4 -1;5 3 4 2 -1;1 6 4 6 -1;1 6 3 5 0];
                    app.solV=[2 2 4];
                otherwise
                    %Load a .mat file?
                    app.yourCards={[],[],[]};
                    app.commonCards={[],[],[]};
                    app.moves=[];
                    app.solV=[];
            end
            NumCardsValueChanged(app,[]);
            NumPlayersValueChanged(app,[]);
        end

        % Value changed function: NumCards
        function NumCardsValueChanged(app, event)
            app.EnvelopeC1.Limits(2)=app.numC;
            app.EnvelopeC2.Limits(2)=app.numC;
            app.EnvelopeC3.Limits(2)=app.numC;
        end

        % Value changed function: NumPlayers
        function NumPlayersValueChanged(app, event)
            app.NumYou.Limits(2)=app.numP;
            app.Gcards.RowHeight=repelem("1x",app.numP+2);
            app.EnvelopeCsLabel.Layout.Row=app.numP+2;
            app.EnvelopeC1.Layout.Row=app.numP+2;
            app.EnvelopeC2.Layout.Row=app.numP+2;
            app.EnvelopeC3.Layout.Row=app.numP+2;
            app.CommonCsLabel.Layout.Row=app.numP+1;
            app.CommonC1.Layout.Row=app.numP+1;
            app.CommonC2.Layout.Row=app.numP+1;
            app.CommonC3.Layout.Row=app.numP+1;
            if ~isscalar(app.PlayersCsLabel)
                delete(app.PlayersCsLabel(2:end));
                delete(app.PlayersC1(2:end));
                delete(app.PlayersC2(2:end));
                delete(app.PlayersC3(2:end));
                app.PlayersCsLabel(app.numP+1:end)=[];
                app.PlayersC1(app.numP+1:end)=[];
                app.PlayersC2(app.numP+1:end)=[];
                app.PlayersC3(app.numP+1:end)=[];
            end
            for n=2:app.numP
                app.PlayersCsLabel(n)=copyobj(app.PlayersCsLabel(1),app.Gcards);
                app.PlayersCsLabel(n).Layout.Row=n;
                app.PlayersCsLabel(n).Text=replace(app.PlayersCsLabel(1).Text,"1",num2str(n));
                app.PlayersC1(n)=copyobj(app.PlayersC1(1),app.Gcards);
                app.PlayersC1(n).Layout.Row=n;
                app.PlayersC2(n)=copyobj(app.PlayersC2(1),app.Gcards);
                app.PlayersC2(n).Layout.Row=n;
                app.PlayersC3(n)=copyobj(app.PlayersC3(1),app.Gcards);
                app.PlayersC3(n).Layout.Row=n;
            end
            NumYouValueChanged(app,[]);
            gameLoaded(app);
        end

        % Value changed function: NumYou
        function NumYouValueChanged(app, event)
            set(app.PlayersCsLabel(:),BackgroundColor='none');
            app.PlayersCsLabel(app.numY).BackgroundColor=app.pyColor;
        end

        % Button pushed function: LetsPlay
        function LetsPlayButtonPushed(app, event)
            if contains(app.LetsPlay.Text(2),"SOLVE") && isempty(app.moves)
                return
            end
            %% (De)Activate Panels
            app.GameSetupPanel.Enable="off";
            app.CardsDistPanel.Enable="on";
            app.MovesListPanel.Enable="on";
            %% Solve Mode: Populate first move
            app.Moves.Data=app.movesTable{1,:};
            if height(app.Moves.StyleConfigurations)>5 %Keep the 5 basic styles applied to the table in startup!
                removeStyle(app.Moves,6:height(app.Moves.StyleConfigurations));
            end
            applyCellStyles(app,1);
            %% Play Mode: How to start?
        end

        % Button pushed function: NextMove
        function NextMoveButtonPushed(app, event)
            numMoves=height(app.Moves.Data);
            if numMoves==height(app.moves)
                uialert(app.clFigure,"That's all the moves! Guess the envelope's contents now.","Time for a solution!🤔","Icon","info");
                k=1; %Only the confirmed cards are now shown!
            else
                app.Moves.Data=[app.Moves.Data;app.movesTable{numMoves+1,:}];
                k=0; %Show all possible cards...
                applyCellStyles(app,numMoves+1);
                scroll(app.Moves,"bottom");
            end
            %Fill in the inferences till this point!
            kmat=inferCards(app);
            for m=setdiff(1:app.numP,app.numY)
                app.PlayersC1(m).Value="["+strjoin(string(find(squeeze(kmat(m,:,1))>k)),",")+"]";
                app.PlayersC2(m).Value="["+strjoin(string(find(squeeze(kmat(m,:,2))>k)),",")+"]";
                app.PlayersC3(m).Value="["+strjoin(string(find(squeeze(kmat(m,:,3))>k)),",")+"]";
            end
        end

        % Button pushed function: Check
        function CheckButtonPushed(app, event)
            if isequal(app.solV(:)',app.solE) %Check the envelope
                uialert(app.clFigure,["Congratulations! Clueless No More!";"You Did It!"],"Game Over!👏","Icon","success");
                % (De)Activate Panels
                app.GameSetupPanel.Enable="on";
                app.CardsDistPanel.Enable="off";
                app.MovesListPanel.Enable="off";
                % Reset the UI elements too or not?
            else %Keep trying
                uialert(app.clFigure,"Sorry! Keep Trying...","Clueless As Ever!😔","Icon","info");
            end
        end

        % Close request function: clFigure
        function clFigureCloseRequest(app, event)
            delete(app)
            % More nuanced closure for later!
            % if ~isempty(app.speaker)
            %     SpeakAsync(app.speaker,'Exit, Really?');
            % end
            % BName=uiconfirm(app.clFigure,'Do you want to exit this clueless game?','Exit?','Options',{'YES','NO'},'DefaultOption',2,'CancelOption',2);
            % switch BName
            %     case 'YES'
            %         if ~isempty(app.speaker)
            %             SpeakAsync(app.speaker,'Lord Ned is NOT pleased!');
            %         end
            %         if exist(app.clmat,'file')==2
            %             SuF=app.currpath;
            %             save(app.clmat,'SuF','-append');
            %             if app.AppTheme.Enable
            %                 load(app.clmat,'Opts');
            %                 if Opts.ApThm~=app.AppTheme.State
            %                     Opts.ApThm=~Opts.ApThm;
            %                     save(app.clmat,'Opts','-append');
            %                 end
            %             end
            %         end
            %         closereq
            %     case 'NO'
            %         if ~isempty(app.speaker)
            %             SpeakAsync(app.speaker,'Lord Ned is pleased!');
            %         end
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.clFigure = uifigure('Visible', 'off');
            app.clFigure.Name = 'Clueless Game';
            app.clFigure.CloseRequestFcn = createCallbackFcn(app, @clFigureCloseRequest, true);
            app.GclBoard = uigridlayout(app.clFigure);
            app.clBoard = uipanel(app.GclBoard);
            app.GclLayout = uigridlayout(app.clBoard);
            app.GameSetupPanel = uipanel(app.GclLayout);
            app.GameSetupPanel.Title = 'Game Setup';
            app.Gsetup = uigridlayout(app.GameSetupPanel);
            app.NumCatsLabel = uilabel(app.Gsetup);
            app.NumCatsLabel.Text = 'Number of Categories:';
            app.NumCats = uispinner(app.Gsetup);
            app.NumCats.Limits = [3 5];
            app.NumCats.RoundFractionalValues = 'on';
            app.NumCats.ValueDisplayFormat = '%.0f';
            app.NumCats.Editable = 'off';
            app.NumCats.Enable = 'off';
            app.NumCats.Value = 3;
            app.NumCardsLabel = uilabel(app.Gsetup);
            app.NumCardsLabel.Text = 'Number of Cards:';
            app.NumCards = uispinner(app.Gsetup);
            app.NumCards.Limits = [4 8];
            app.NumCards.RoundFractionalValues = 'on';
            app.NumCards.ValueDisplayFormat = '%.0f';
            app.NumCards.ValueChangedFcn = createCallbackFcn(app, @NumCardsValueChanged, true);
            app.NumCards.Enable = 'off';
            app.NumCards.Value = 4;
            app.NumPlayersLabel = uilabel(app.Gsetup);
            app.NumPlayersLabel.Text = 'Number of Players:';
            app.NumPlayers = uispinner(app.Gsetup);
            app.NumPlayers.Limits = [4 8];
            app.NumPlayers.RoundFractionalValues = 'on';
            app.NumPlayers.ValueDisplayFormat = '%.0f';
            app.NumPlayers.ValueChangedFcn = createCallbackFcn(app, @NumPlayersValueChanged, true);
            app.NumPlayers.Enable = 'off';
            app.NumPlayers.Value = 4;
            app.NumYouLabel = uilabel(app.Gsetup);
            app.NumYouLabel.Text = 'You are Player #:';
            app.NumYou = uispinner(app.Gsetup);
            app.NumYou.Limits = [1 8];
            app.NumYou.RoundFractionalValues = 'on';
            app.NumYou.ValueDisplayFormat = '%.0f';
            app.NumYou.ValueChangedFcn = createCallbackFcn(app, @NumYouValueChanged, true);
            app.NumYou.Enable = 'off';
            app.NumYou.Value = 4;
            app.LetsPlay = uibutton(app.Gsetup, 'push');
            app.LetsPlay.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlay.Text = {'Lord Ned Commands.'; 'LET''S SOLVE!'};
            app.ModeSwitch = uiswitch(app.Gsetup, 'slider');
            app.ModeSwitch.Items = {'Play Mode', 'Solve Mode'};
            app.ModeSwitch.Enable = 'off';
            app.ModeSwitch.Value = 'Solve Mode';
            app.LoadGame = uidropdown(app.Gsetup);
            app.LoadGame.Items = {'Select a Game:', 'Game #00', 'Game #01', 'Game #02'};
            app.LoadGame.ItemsData = [-1 0 1 2];
            app.LoadGame.ValueChangedFcn = createCallbackFcn(app, @LoadGameValueChanged, true);
            app.LoadGame.Value = -1;
            app.CardsDistPanel = uipanel(app.GclLayout);
            app.CardsDistPanel.Enable = 'off';
            app.CardsDistPanel.Title = 'Cards Distribution';
            app.Gcards = uigridlayout(app.CardsDistPanel);
            app.PlayersCsLabel = uilabel(app.Gcards);
            app.PlayersCsLabel.Text = '👤1';
            app.PlayersC1 = uieditfield(app.Gcards, 'text');
            app.PlayersC1.Editable = 'off';
            app.PlayersC1.Value = '{?}';
            app.PlayersC2 = uieditfield(app.Gcards, 'text');
            app.PlayersC2.Editable = 'off';
            app.PlayersC2.Value = '{?}';
            app.PlayersC3 = uieditfield(app.Gcards, 'text');
            app.PlayersC3.Editable = 'off';
            app.PlayersC3.Value = '{?}';
            app.CommonCsLabel = uilabel(app.Gcards);
            app.CommonCsLabel.Text = '🃏';
            app.CommonC1 = uieditfield(app.Gcards, 'text');
            app.CommonC1.Editable = 'off';
            app.CommonC1.Value = '{?}';
            app.CommonC2 = uieditfield(app.Gcards, 'text');
            app.CommonC2.Editable = 'off';
            app.CommonC2.Value = '{?}';
            app.CommonC3 = uieditfield(app.Gcards, 'text');
            app.CommonC3.Editable = 'off';
            app.CommonC3.Value = '{?}';
            app.EnvelopeCsLabel = uilabel(app.Gcards);
            app.EnvelopeCsLabel.Text = '✉️';
            app.EnvelopeC1 = uispinner(app.Gcards);
            app.EnvelopeC1.Limits = [0 4];
            app.EnvelopeC1.RoundFractionalValues = 'on';
            app.EnvelopeC1.ValueDisplayFormat = '%.0f';
            app.EnvelopeC2 = uispinner(app.Gcards);
            app.EnvelopeC2.Limits = [0 4];
            app.EnvelopeC2.RoundFractionalValues = 'on';
            app.EnvelopeC2.ValueDisplayFormat = '%.0f';
            app.EnvelopeC3 = uispinner(app.Gcards);
            app.EnvelopeC3.Limits = [0 4];
            app.EnvelopeC3.RoundFractionalValues = 'on';
            app.EnvelopeC3.ValueDisplayFormat = '%.0f';
            app.MovesListPanel = uipanel(app.GclLayout);
            app.MovesListPanel.Enable = 'off';
            app.MovesListPanel.Title = 'Moves List';
            app.Gmoves = uigridlayout(app.MovesListPanel);
            app.NextMove = uibutton(app.Gmoves, 'push');
            app.NextMove.ButtonPushedFcn = createCallbackFcn(app, @NextMoveButtonPushed, true);
            app.NextMove.Text = 'Next Move >';
            app.Check = uibutton(app.Gmoves, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.Check.Text = '< Check Your Solution...';
            app.Moves = uitable(app.Gmoves);
            app.Moves.ColumnName = {'Player'; 'Asked'; 'Cat.1'; 'Cat.2'; 'Cat.3'; 'Reply'};
            app.Moves.SelectionType = 'row';
            % Show the figure after all components are created
            app.clFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clueless

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.clFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.clFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.clFigure)
        end
    end
end